classdef APP_DieboldLiKalmanForecasting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DieboldLiKalmanSSMForecastingUIFigure  matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        VisualizeYieldsTab             matlab.ui.container.Tab
        GridLayout_3                   matlab.ui.container.GridLayout
        LoadButton                     matlab.ui.control.Button
        Image_2                        matlab.ui.control.Image
        UITable                        matlab.ui.control.Table
        YieldLamp                      matlab.ui.control.Lamp
        EditField                      matlab.ui.control.EditField
        Image3                         matlab.ui.control.Image
        PlotButton                     matlab.ui.control.Button
        startDate                      matlab.ui.control.DatePicker
        endDate                        matlab.ui.control.DatePicker
        ClimateStressButton            matlab.ui.control.Button
        UIAxes_YieldCurves             matlab.ui.control.UIAxes
        FitARSSMKalmanModelTab         matlab.ui.container.Tab
        GridLayout                     matlab.ui.container.GridLayout
        Image                          matlab.ui.control.Image
        Image2                         matlab.ui.control.Image
        FitVectorAutoregressiveModelSSMKalmnModelButton  matlab.ui.control.Button
        FitLamp_3                      matlab.ui.control.Lamp
        FitLamp_2                      matlab.ui.control.Lamp
        FitLamp_1                      matlab.ui.control.Lamp
        ImageFit_StateTransition       matlab.ui.control.Image
        ImageFit_Measurement           matlab.ui.control.Image
        EqnsButton                     matlab.ui.control.StateButton
        UIAxes_Level                   matlab.ui.control.UIAxes
        UIAxes_Curvature               matlab.ui.control.UIAxes
        UIAxes_Slope                   matlab.ui.control.UIAxes
        UIAxes_VAR_ARTerms             matlab.ui.control.UIAxes
        UIAxes_SSM_ARTerms             matlab.ui.control.UIAxes
        FitSSMAugmentedbyMacroFactorsTab  matlab.ui.container.Tab
        GridLayout_4                   matlab.ui.container.GridLayout
        Image_4                        matlab.ui.control.Image
        Image2_2                       matlab.ui.control.Image
        FitSSMKalmnModelextendbyMacroFactorsButton  matlab.ui.control.Button
        ImageFitMacro_StateTransition  matlab.ui.control.Image
        ImageFitMacro_MeasurementVariables  matlab.ui.control.Image
        ImageFitMacro_TransitionVariables  matlab.ui.control.Image
        EqnsButton_2                   matlab.ui.control.StateButton
        ImageFitMacro_StateTransition_2  matlab.ui.control.Image
        UIAxes_MacroModelParams        matlab.ui.control.UIAxes
        UIAxes_A3                      matlab.ui.control.UIAxes
        UIAxes_A2                      matlab.ui.control.UIAxes
        UIAxes_A1                      matlab.ui.control.UIAxes
        ForecastYieldTab               matlab.ui.container.Tab
        GridLayout_2                   matlab.ui.container.GridLayout
        ForecastYieldsButton           matlab.ui.control.Button
        Image_3                        matlab.ui.control.Image
        YieldLamp_Forecast             matlab.ui.control.Lamp
        AnimateClimateScenarioYieldsIllustrativeButton_2  matlab.ui.control.StateButton
        ClimateEvoluationAxes          matlab.ui.control.UIAxes
        UIAxes_YieldForecast           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Yield_TableIndex % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: 
        % FitVectorAutoregressiveModelSSMKalmnModelButton
        function FitVectorAutoregressiveModelSSMKalmnModelButtonPushed(app, event)
            app.FitLamp_1.Color = [0.85,0.33,0.10];
            app.FitLamp_2.Color = [0.85,0.33,0.10];
            app.FitLamp_3.Color = [0.85,0.33,0.10];
            cla(app.UIAxes_Level)
            cla(app.UIAxes_Slope)
            cla(app.UIAxes_Curvature)
            cla(app.UIAxes_VAR_ARTerms)
            cla(app.UIAxes_SSM_ARTerms)
            % Uncomment to get a real estimate on timing of regression
            %[EstMdlVAR,residuals,beta] = cs.fit2StepYieldCurveModel();
            pause(0.5)
            cs.plotLevelEstimate(app.UIAxes_Level)
            app.FitLamp_1.Color = [0.47,0.67,0.19];
            pause(0.5)
            app.FitLamp_2.Color = [0.85,0.33,0.10];
            % Uncomment to get a real estimate on timing of MLE
            %[EstMdlSSM,lambda,mu,deflatedYields,estimatedStates] = cs.fitSSMofAYieldCurve();
            pause(0.5)
            app.FitLamp_2.Color = [0.47,0.67,0.19];
            
            % VISUALIZATIon
            app.FitLamp_3.Color = [0.85,0.33,0.10];
            cs.plotSlopeEstimate(app.UIAxes_Slope)
            pause(0.5)
            cs.plotCurvatureEstimate(app.UIAxes_Curvature)
            pause(0.5)
            cs.compareARInteractions(app.UIAxes_VAR_ARTerms,app.UIAxes_SSM_ARTerms)
            colormap(app.DieboldLiKalmanSSMForecastingUIFigure,'jet')
            app.FitLamp_3.Color = [0.47,0.67,0.19];
        end

        % Button down function: UIAxes_YieldCurves
        function UIAxes_YieldCurvesButtonDown(app, event)
            
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            L = load('Data_DieboldLi.mat');
            cla(app.UIAxes_YieldCurves)
            app.UITable.Visible = false;
            for i = 1:5
                app.YieldLamp.Color = [0.85,0.33,0.10];
                pause(0.1)
                app.YieldLamp.Color = [0.47,0.67,0.19];
                pause(0.1)
            end
            app.UITable.Visible = true;
            app.UITable.Data = L.DataTable.Variables;
            app.UITable.ColumnName = L.DataTable.Properties.VariableNames;
            app.UITable.RowName    = datestr(L.DataTable.Properties.RowTimes);
            app.startDate.Value    = L.DataTable.Properties.RowTimes(1);
            app.endDate.Value    = L.DataTable.Properties.RowTimes(end);
            app.UITable.BackgroundColor = [1 1 1 ;0.72,0.94,0.99];
            plot(app.UIAxes_YieldCurves,L.DataTable.Properties.RowTimes,L.DataTable{:,1:end-4})
            recessionplot('axes',app.UIAxes_YieldCurves)
            legend(app.UIAxes_YieldCurves,L.DataTable.Properties.VariableNames(1:end-4),'Location','NorthWest')
            title(app.UIAxes_YieldCurves,'Yield Curve Evolution')
        end

        % Image clicked function: Image3
        function Image3Clicked(app, event)
            web('https://fred.stlouisfed.org/ ')
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            if isempty(app.Yield_TableIndex)
                LoadButtonPushed(app, event)
                app.Yield_TableIndex = [(1:height(app.UITable))' 18*ones(height(app.UITable),1)];
            end
            reset(app.UIAxes_YieldCurves)
            cla(app.UIAxes_YieldCurves)
            iDates = datetime(app.UITable.RowName) >= app.startDate.Value & datetime(app.UITable.RowName) <= app.endDate.Value;
            Dates  = datetime(app.UITable.RowName);
            Dates  = Dates(iDates);
            iCols  = unique(app.Yield_TableIndex(:,2));
            selectedData = app.UITable.Data(iDates,iCols);
            plot(app.UIAxes_YieldCurves,Dates,selectedData)
            recessionplot('axes',app.UIAxes_YieldCurves)
            legend(app.UIAxes_YieldCurves,app.UITable.ColumnName(iCols))
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            app.Yield_TableIndex = indices;
        end

        % Value changed function: EqnsButton
        function EqnsButtonValueChanged(app, event)
            value = app.EqnsButton.Value;
            if value
                app.ImageFit_StateTransition.Visible = true;
                app.ImageFit_Measurement.Visible = true;
            else
                app.ImageFit_StateTransition.Visible = false;
                app.ImageFit_Measurement.Visible = false;
            end
        end

        % Value changed function: EqnsButton_2
        function EqnsButton_2ValueChanged(app, event)
            value = app.EqnsButton_2.Value;
            if value
                app.ImageFitMacro_StateTransition.Visible = true;
                app.ImageFitMacro_TransitionVariables.Visible = true;
                app.ImageFitMacro_MeasurementVariables.Visible = true;
            else
                app.ImageFitMacro_StateTransition.Visible = false;
                app.ImageFitMacro_TransitionVariables.Visible = false;
                app.ImageFitMacro_MeasurementVariables.Visible = false;            
            end            
        end

        % Button pushed function: 
        % FitSSMKalmnModelextendbyMacroFactorsButton
        function FitSSMKalmnModelextendbyMacroFactorsButtonPushed(app, event)
            [EstMdlMacro,estParamsMacro,EstParamCovMacro] = cs.fitSSMMacroYieldCurve();
            bar(app.UIAxes_MacroModelParams,estParamsMacro)
            cs.compareAllModels(app.UIAxes_A1,app.UIAxes_A2,app.UIAxes_A3)
            colormap(app.DieboldLiKalmanSSMForecastingUIFigure,'jet')
        end

        % Button pushed function: ForecastYieldsButton
        function ForecastYieldsButtonPushed(app, event)
            cla(app.UIAxes_YieldForecast)
            for i = 1:5
                app.YieldLamp_Forecast.Color = [0.85,0.33,0.10];
                pause(0.1)
                app.YieldLamp_Forecast.Color = [0.47,0.67,0.19];
                pause(0.1)
            end
            horizon = 20*12;   % forecast horizon (months)
            L1 = cs.loadDiebolLiData();
            L2 = load('SSMModel.mat');
            [intercept,deflatedYields,deflatedStates,estimatedStates] = cs.deflateYields();
            [forecastedDeflatedYields,mse] = forecast(L2.EstMdlSSM,horizon,deflatedYields);
            forecastedYields = forecastedDeflatedYields + intercept';
            
            plot(app.UIAxes_YieldForecast,L1.dates,L1.yields)
            hold(app.UIAxes_YieldForecast,'on')
            plot(app.UIAxes_YieldForecast,L1.dates(end)+(1:horizon)',forecastedYields,'k-.')
            title(app.UIAxes_YieldForecast,'Forecast Yields - Appended to Yield Curves')
        end

        % Callback function
        function AnimateClimateScenarioYieldsIllustrativeButtonPushed(app, event)
            cla(app.ClimateEvoluationAxes)
            for i = 1:5
                app.YieldLamp_Forecast.Color = [0.85,0.33,0.10];
                pause(0.1)
                app.YieldLamp_Forecast.Color = [0.47,0.67,0.19];
                pause(0.1)
            end
            horizon = 5*12;   % forecast horizon (months)
            L1 = cs.loadDiebolLiData();
            L2 = load('SSMModel.mat');
            [intercept,deflatedYields,deflatedStates,estimatedStates] = cs.deflateYields();
            [forecastedDeflatedYields,mse] = forecast(L2.EstMdlSSM,horizon,deflatedYields);
            forecastedYields = forecastedDeflatedYields + intercept';
            for i = 1:horizon
                plot(app.ClimateEvoluationAxes,L1.maturities,forecastedYields(i,:))
                title(app.ClimateEvoluationAxes,['Year: ' datestr(L1.dates(end)+i)])
                pause(0.1)
                cla(app.ClimateEvoluationAxes)
            end
        end

        % Button down function: UIAxes_A3
        function UIAxes_A3ButtonDown(app, event)
            
        end

        % Button pushed function: ClimateStressButton
        function ClimateStressButtonPushed(app, event)
            if isempty(app.UITable.Data)
                LoadButtonPushed(app, event)
            else
                reset(app.UIAxes_YieldCurves)
                cla(app.UIAxes_YieldCurves)
                iDates = datetime(app.UITable.RowName) >= app.startDate.Value & datetime(app.UITable.RowName) <= app.endDate.Value;
                Dates  = datetime(app.UITable.RowName);
                Dates  = Dates(iDates);
                iCols  = unique(app.Yield_TableIndex(:,2));
                selectedData = app.UITable.Data(iDates,iCols);
                plot(app.UIAxes_YieldCurves,Dates,[selectedData selectedData+0.01*selectedData selectedData-0.01*selectedData])
                recessionplot('axes',app.UIAxes_YieldCurves)
                lNames = [app.UITable.ColumnName(iCols); '+1%'; '-1%'];
                legend(app.UIAxes_YieldCurves,lNames)
            end            
        end

        % Value changed function: 
        % AnimateClimateScenarioYieldsIllustrativeButton_2
        function AnimateClimateScenarioYieldsIllustrativeButton_2ValueChanged(app, event)
            value = app.AnimateClimateScenarioYieldsIllustrativeButton_2.Value;
            cla(app.ClimateEvoluationAxes)
            for i = 1:5
                app.YieldLamp_Forecast.Color = [0.85,0.33,0.10];
                pause(0.1)
                app.YieldLamp_Forecast.Color = [0.47,0.67,0.19];
                pause(0.1)
            end
            horizon = 5*12;   % forecast horizon (months)
            L1 = cs.loadDiebolLiData();
            L2 = load('SSMModel.mat');
            [intercept,deflatedYields,deflatedStates,estimatedStates] = cs.deflateYields();
            [forecastedDeflatedYields,mse] = forecast(L2.EstMdlSSM,horizon,deflatedYields);
            forecastedYields = forecastedDeflatedYields + intercept';
            while app.AnimateClimateScenarioYieldsIllustrativeButton_2.Value
                for i = 1:horizon
                    if app.AnimateClimateScenarioYieldsIllustrativeButton_2.Value
                        plot(app.ClimateEvoluationAxes,L1.maturities,forecastedYields(i,:))
                        title(app.ClimateEvoluationAxes,['Year: ' datestr(L1.dates(end)+i)])
                        pause(0.1)
                        cla(app.ClimateEvoluationAxes)
                    else
                        plot(app.ClimateEvoluationAxes,L1.maturities,forecastedYields(i,:))
                        title(app.ClimateEvoluationAxes,['Year: ' datestr(L1.dates(end)+i)])                        
                        break
                    end
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DieboldLiKalmanSSMForecastingUIFigure = uifigure('Visible', 'off');
            app.DieboldLiKalmanSSMForecastingUIFigure.Name = 'Diebold Li-Kalman SSM Forecasting';
            app.DieboldLiKalmanSSMForecastingUIFigure.Icon = 'logo_L35.png';
            app.TabGroup = uitabgroup(app.DieboldLiKalmanSSMForecastingUIFigure);
            app.VisualizeYieldsTab = uitab(app.TabGroup);
            app.VisualizeYieldsTab.Title = 'Visualize Yields';
            app.GridLayout_3 = uigridlayout(app.VisualizeYieldsTab);
            app.LoadButton = uibutton(app.GridLayout_3, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.Image_2 = uiimage(app.GridLayout_3);
            app.Image_2.ImageSource = 'logo_L35.png';
            app.UITable = uitable(app.GridLayout_3);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.YieldLamp = uilamp(app.GridLayout_3);
            app.EditField = uieditfield(app.GridLayout_3, 'text');
            app.EditField.Value = 'US Treasury unsmoothed Fama-Bliss zero-coupon yields (Jan 1972-Dec 2000)';
            app.Image3 = uiimage(app.GridLayout_3);
            app.Image3.ImageClickedFcn = createCallbackFcn(app, @Image3Clicked, true);
            app.Image3.ImageSource = 'FREDIcon.png';
            app.PlotButton = uibutton(app.GridLayout_3, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.startDate = uidatepicker(app.GridLayout_3);
            app.endDate = uidatepicker(app.GridLayout_3);
            app.ClimateStressButton = uibutton(app.GridLayout_3, 'push');
            app.ClimateStressButton.ButtonPushedFcn = createCallbackFcn(app, @ClimateStressButtonPushed, true);
            app.ClimateStressButton.Text = 'Climate Stress';
            app.UIAxes_YieldCurves = uiaxes(app.GridLayout_3);
            title(app.UIAxes_YieldCurves, 'Title')
            xlabel(app.UIAxes_YieldCurves, 'X')
            ylabel(app.UIAxes_YieldCurves, 'Y')
            zlabel(app.UIAxes_YieldCurves, 'Z')
            app.UIAxes_YieldCurves.ButtonDownFcn = createCallbackFcn(app, @UIAxes_YieldCurvesButtonDown, true);
            app.FitARSSMKalmanModelTab = uitab(app.TabGroup);
            app.FitARSSMKalmanModelTab.Title = 'Fit AR & SSM/Kalman Model';
            app.GridLayout = uigridlayout(app.FitARSSMKalmanModelTab);
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = 'logo_L35.png';
            app.Image2 = uiimage(app.GridLayout);
            app.Image2.ImageSource = 'DieboldLiModel.png';
            app.FitVectorAutoregressiveModelSSMKalmnModelButton = uibutton(app.GridLayout, 'push');
            app.FitVectorAutoregressiveModelSSMKalmnModelButton.ButtonPushedFcn = createCallbackFcn(app, @FitVectorAutoregressiveModelSSMKalmnModelButtonPushed, true);
            app.FitVectorAutoregressiveModelSSMKalmnModelButton.Text = 'Fit Vector Autoregressive Model & SSM/Kalmn Model';
            app.FitLamp_3 = uilamp(app.GridLayout);
            app.FitLamp_2 = uilamp(app.GridLayout);
            app.FitLamp_1 = uilamp(app.GridLayout);
            app.ImageFit_StateTransition = uiimage(app.GridLayout);
            app.ImageFit_StateTransition.Visible = 'off';
            app.ImageFit_StateTransition.ImageSource = 'StateTransitionFunction.png';
            app.ImageFit_Measurement = uiimage(app.GridLayout);
            app.ImageFit_Measurement.Visible = 'off';
            app.ImageFit_Measurement.ImageSource = 'MeasurementEquation.png';
            app.EqnsButton = uibutton(app.GridLayout, 'state');
            app.EqnsButton.ValueChangedFcn = createCallbackFcn(app, @EqnsButtonValueChanged, true);
            app.EqnsButton.Text = 'Eqns.';
            app.UIAxes_Level = uiaxes(app.GridLayout);
            title(app.UIAxes_Level, 'Title')
            xlabel(app.UIAxes_Level, 'X')
            ylabel(app.UIAxes_Level, 'Y')
            zlabel(app.UIAxes_Level, 'Z')
            app.UIAxes_Curvature = uiaxes(app.GridLayout);
            title(app.UIAxes_Curvature, 'Title')
            xlabel(app.UIAxes_Curvature, 'X')
            ylabel(app.UIAxes_Curvature, 'Y')
            zlabel(app.UIAxes_Curvature, 'Z')
            app.UIAxes_Slope = uiaxes(app.GridLayout);
            title(app.UIAxes_Slope, 'Title')
            xlabel(app.UIAxes_Slope, 'X')
            ylabel(app.UIAxes_Slope, 'Y')
            zlabel(app.UIAxes_Slope, 'Z')
            app.UIAxes_VAR_ARTerms = uiaxes(app.GridLayout);
            title(app.UIAxes_VAR_ARTerms, 'Title')
            xlabel(app.UIAxes_VAR_ARTerms, 'X')
            ylabel(app.UIAxes_VAR_ARTerms, 'Y')
            zlabel(app.UIAxes_VAR_ARTerms, 'Z')
            app.UIAxes_SSM_ARTerms = uiaxes(app.GridLayout);
            title(app.UIAxes_SSM_ARTerms, 'Title')
            xlabel(app.UIAxes_SSM_ARTerms, 'X')
            ylabel(app.UIAxes_SSM_ARTerms, 'Y')
            zlabel(app.UIAxes_SSM_ARTerms, 'Z')
            app.FitSSMAugmentedbyMacroFactorsTab = uitab(app.TabGroup);
            app.FitSSMAugmentedbyMacroFactorsTab.Title = 'Fit SSM Augmented by Macro Factors';
            app.GridLayout_4 = uigridlayout(app.FitSSMAugmentedbyMacroFactorsTab);
            app.Image_4 = uiimage(app.GridLayout_4);
            app.Image_4.ImageSource = 'logo_L35.png';
            app.Image2_2 = uiimage(app.GridLayout_4);
            app.Image2_2.ImageSource = 'DieboldLiModel.png';
            app.FitSSMKalmnModelextendbyMacroFactorsButton = uibutton(app.GridLayout_4, 'push');
            app.FitSSMKalmnModelextendbyMacroFactorsButton.ButtonPushedFcn = createCallbackFcn(app, @FitSSMKalmnModelextendbyMacroFactorsButtonPushed, true);
            app.FitSSMKalmnModelextendbyMacroFactorsButton.Text = 'Fit SSM/Kalmn Model extend by Macro Factors';
            app.ImageFitMacro_StateTransition = uiimage(app.GridLayout_4);
            app.ImageFitMacro_StateTransition.Visible = 'off';
            app.ImageFitMacro_StateTransition.ImageSource = 'MacorExpandedStateSpace.png';
            app.ImageFitMacro_MeasurementVariables = uiimage(app.GridLayout_4);
            app.ImageFitMacro_MeasurementVariables.Visible = 'off';
            app.ImageFitMacro_MeasurementVariables.ImageSource = 'MeasurementFunction.png';
            app.ImageFitMacro_TransitionVariables = uiimage(app.GridLayout_4);
            app.ImageFitMacro_TransitionVariables.Visible = 'off';
            app.ImageFitMacro_TransitionVariables.ImageSource = 'TransferFunction.png';
            app.EqnsButton_2 = uibutton(app.GridLayout_4, 'state');
            app.EqnsButton_2.ValueChangedFcn = createCallbackFcn(app, @EqnsButton_2ValueChanged, true);
            app.EqnsButton_2.Text = 'Eqns.';
            app.ImageFitMacro_StateTransition_2 = uiimage(app.GridLayout_4);
            app.ImageFitMacro_StateTransition_2.ImageSource = 'ModelParams81Explain.png';
            app.UIAxes_MacroModelParams = uiaxes(app.GridLayout_4);
            title(app.UIAxes_MacroModelParams, 'Title')
            xlabel(app.UIAxes_MacroModelParams, 'X')
            ylabel(app.UIAxes_MacroModelParams, 'Y')
            zlabel(app.UIAxes_MacroModelParams, 'Z')
            app.UIAxes_A3 = uiaxes(app.GridLayout_4);
            title(app.UIAxes_A3, 'Title')
            xlabel(app.UIAxes_A3, 'X')
            ylabel(app.UIAxes_A3, 'Y')
            zlabel(app.UIAxes_A3, 'Z')
            app.UIAxes_A3.ButtonDownFcn = createCallbackFcn(app, @UIAxes_A3ButtonDown, true);
            app.UIAxes_A2 = uiaxes(app.GridLayout_4);
            title(app.UIAxes_A2, 'Title')
            xlabel(app.UIAxes_A2, 'X')
            ylabel(app.UIAxes_A2, 'Y')
            zlabel(app.UIAxes_A2, 'Z')
            app.UIAxes_A1 = uiaxes(app.GridLayout_4);
            title(app.UIAxes_A1, 'Title')
            xlabel(app.UIAxes_A1, 'X')
            ylabel(app.UIAxes_A1, 'Y')
            zlabel(app.UIAxes_A1, 'Z')
            app.ForecastYieldTab = uitab(app.TabGroup);
            app.ForecastYieldTab.Title = 'Forecast Yield';
            app.GridLayout_2 = uigridlayout(app.ForecastYieldTab);
            app.ForecastYieldsButton = uibutton(app.GridLayout_2, 'push');
            app.ForecastYieldsButton.ButtonPushedFcn = createCallbackFcn(app, @ForecastYieldsButtonPushed, true);
            app.ForecastYieldsButton.Text = 'Forecast Yields';
            app.Image_3 = uiimage(app.GridLayout_2);
            app.Image_3.ImageSource = 'logo_L35.png';
            app.YieldLamp_Forecast = uilamp(app.GridLayout_2);
            app.AnimateClimateScenarioYieldsIllustrativeButton_2 = uibutton(app.GridLayout_2, 'state');
            app.AnimateClimateScenarioYieldsIllustrativeButton_2.ValueChangedFcn = createCallbackFcn(app, @AnimateClimateScenarioYieldsIllustrativeButton_2ValueChanged, true);
            app.AnimateClimateScenarioYieldsIllustrativeButton_2.Text = 'Animate Climate Scenario Yields (Illustrative)';
            app.ClimateEvoluationAxes = uiaxes(app.GridLayout_2);
            title(app.ClimateEvoluationAxes, 'Title')
            xlabel(app.ClimateEvoluationAxes, 'X')
            ylabel(app.ClimateEvoluationAxes, 'Y')
            zlabel(app.ClimateEvoluationAxes, 'Z')
            app.UIAxes_YieldForecast = uiaxes(app.GridLayout_2);
            title(app.UIAxes_YieldForecast, 'Title')
            xlabel(app.UIAxes_YieldForecast, 'X')
            ylabel(app.UIAxes_YieldForecast, 'Y')
            zlabel(app.UIAxes_YieldForecast, 'Z')
            % Show the figure after all components are created
            app.DieboldLiKalmanSSMForecastingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP_DieboldLiKalmanForecasting

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DieboldLiKalmanSSMForecastingUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DieboldLiKalmanSSMForecastingUIFigure)
        end
    end
end